classdef Latest_SISO_APP_06152026 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Button2_2                    matlab.ui.control.Button
        ResetTVWSOpeatorButton       matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        SimulationTab                matlab.ui.container.Tab
        Panel_18                     matlab.ui.container.Panel
        trialLabel_2                 matlab.ui.control.Label
        UIAxes6_2                    matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
        UIAxes1_2                    matlab.ui.control.UIAxes
        otpr3                        matlab.ui.control.Button
        otpr2                        matlab.ui.control.Button
        Panel_17                     matlab.ui.container.Panel
        UIAxes4_2                    matlab.ui.control.UIAxes
        UIAxes10_2                   matlab.ui.control.UIAxes
        UIAxes11_2                   matlab.ui.control.UIAxes
        UIAxes9_2                    matlab.ui.control.UIAxes
        Panel_15                     matlab.ui.container.Panel
        AccessBTSLabel_2             matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        SimulationControlsLabel      matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        RunTimeLabel                 matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        TVWSOperatorButton           matlab.ui.control.Button
        Panel_9                      matlab.ui.container.Panel
        Image_2                      matlab.ui.control.Image
        TVWSOperatorLabel            matlab.ui.control.Label
        BaseTranscieverStationLabel  matlab.ui.control.Label
        success                      matlab.ui.control.Image
        otpr6                        matlab.ui.control.Button
        error1                       matlab.ui.control.Image
        error2                       matlab.ui.control.Image
        otpr1                        matlab.ui.control.Button
        otpr4                        matlab.ui.control.Button
        otpr8                        matlab.ui.control.Button
        otpr7                        matlab.ui.control.Button
        otpr5                        matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Panel_12                     matlab.ui.container.Panel
        ThreatMitigationPanelLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Panel_7                      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        EditField_5                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        EditField_1                  matlab.ui.control.EditField
        Panel_6                      matlab.ui.container.Panel
        ThreatDetectionPanelLabel    matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        LegitimateAccessLabel        matlab.ui.control.Label
        IllegalAccessLabel           matlab.ui.control.Label
        EstimatingLabel              matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        ResetSimulationButton        matlab.ui.control.Button
        StartSimulationButton        matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        CharacterizationTab          matlab.ui.container.Tab
        trialLabel                   matlab.ui.control.Label
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        CategorizationTab            matlab.ui.container.Tab
        UIAxes9                      matlab.ui.control.UIAxes
        UIAxes8                      matlab.ui.control.UIAxes
        UIAxes10                     matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        AvgoverTrialTab              matlab.ui.container.Tab
        UIAxes11                     matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        Tab_3                        matlab.ui.container.Tab
        UIAxes14                     matlab.ui.control.UIAxes
        UIAxes13                     matlab.ui.control.UIAxes
        UIAxes12                     matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        %situationIndex = 0;
        %situations cell = {}      
        %simulationActive = false; 
        
        situationIndex double = 0 % Counter for current run, Tracks current situation (1 to 5)
        situationResults = [];    % Stores 5 randomized red/green outcomes
        simulationActive logical = false    % Whether simulation is active or has started
        readyForNext = false;     % Whether ready for next situation
        allDone = false;          % True when all 5 situations are done
        randomOrder double = []   % Randomized order of 1–5 situations
        situations cell = {       % Predefined situations, Table of possible situations
            'OFDM-compliant', ...
            'Jammerless', ...
            'Cyclic prefix-violating', ...
            'OFDM-noncompliant', ...
            'None' % Legitimate
        }
     end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            %AccessButtonStart_runmontecarlo_btn(app, event)

            % Prevent restarting if already running
            if app.simulationActive
                uialert(app.UIFigure, 'Simulation is already running.', 'Notice');
                return;
            end

            app.simulationActive = true;
        
            % Initialize simulation
            app.simulationActive = true;
            app.situationIndex = 0;
            app.readyForNext = true;
            app.allDone = false;
        
            % Randomize 4 reds (0) and 1 green (1)
            results = [zeros(1,4), 1];
            app.situationResults = results(randperm(5));

            app.Lamp.Color = [1 1 1];
             % Lamp orange — ready to start


%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%% configurable parameters
%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Corresponds to Section II (System Model) of the paper: Defines the SISO-OFDM system parameters,
% including antenna counts, channel taps, modulation, and simulation settings for evaluating jammer impact.
num_receive_antennas = 1;                    % 8 par.B:BTS  number of receive antennas
num_transmit_antennas = 1;                    % 2 par.U:OPERATOR number of transmit antennas and data streams
num_channel_taps = 2;                        % 4 par.L: number of channel taps (multipath delay spread)
cyclic_prefix_length = 16;                   % par.P: cyclic prefix length (to combat inter-symbol interference)
num_subcarriers = 64;                        % par.N_sc: number of total subcarriers (FFT size for OFDM)
used_subcarrier_indices = [-26:-22, -20:-8, -6:-1, 1:6, 8:20, 22:26] + 32; % par.tonelist: list of used subcarriers (data-bearing tones), shifted by 32 for DC centering
num_used_subcarriers = length(used_subcarrier_indices); % par.num_tones: total number of active subcarriers
num_ofdm_symbols = 50;                       % par.num_ofdm_symbols: number of OFDM symbols sent per channel realization (temporal diversity)
jammer_power_dB = 30;                        % par.rho_dB: jammer strength relative to signal strength (in dB) - high value simulates strong jamming
modulation_scheme = 'QPSK';                  % par.mod: transmit constellation (modulation scheme: QPSK for 2 bits/symbol)
random_jammer_power_flag = 0;                % par.random_jammer_power: if true, jammer power varies randomly per trial; else fixed at par.rho_dB
    
num_trials = 500;                            % par.trials: number of Monte-Carlo trials (different channel realizations for statistical averaging)
snr_dB_list = -5:1:30;                       % par.SNRdB_list: list of SNR values (in dB) to simulate (signal-to-noise ratio sweep)

plot_flag = 1;                              % par.plot: flag to enable/disable plotting of simulation results
print_messages_flag = 1;                     % par.printmsg: flag to enable/disable printing of progress messages during simulation

rng('shuffle');                                     % Fixed seed for REPRODUCIBILITY (For IEEE paper only)

%% 

%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%% simulation start
%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Corresponds to Section II (System Model): Modulation constellation definition and bit mapping for signal generation.

% create a unique simulation name based on parameters for logging or saving results
%summarzes the given paramenters
simulation_name = [num2str(num_receive_antennas) 'x' num2str(num_transmit_antennas) '_' modulation_scheme '_L', num2str(num_channel_taps) '_P' num2str(cyclic_prefix_length) ...
  '_Nsc' num2str(num_subcarriers) '_Nofd' num2str(num_ofdm_symbols) '_rho' num2str(jammer_power_dB) ...
  '_' num2str(num_trials) 'Trials']; % par.simName
fprintf(simulation_name)

% define the modulation constellation based on the chosen modulation scheme
switch (modulation_scheme)
  case 'BPSK'  % Binary Phase Shift Keying: 2 symbols, 1 bit each
    constellation_symbols = [ -1 1 ]; % par.symbols
  case 'QPSK'  % Quadrature Phase Shift Keying: 4 symbols, 2 bits each
    constellation_symbols = [ ... % par.symbols
      -1-1i,-1+1i, ...
      +1-1i,+1+1i ];
  case '16QAM'  % 16-Quadrature Amplitude Modulation: 16 symbols, 4 bits each
    constellation_symbols = [ ... % par.symbols
      -3-3i,-3-1i,-3+3i,-3+1i, ...
      -1-3i,-1-1i,-1+3i,-1+1i, ...
      +3-3i,+3-1i,+3+3i,+3+1i, ...
      +1-3i,+1-1i,+1+3i,+1+1i ];
  case '64QAM'  % 64-Quadrature Amplitude Modulation: 64 symbols, 6 bits each
    constellation_symbols = [ ... % par.symbols
      -7-7i,-7-5i,-7-1i,-7-3i,-7+7i,-7+5i,-7+1i,-7+3i, ...
      -5-7i,-5-5i,-5-1i,-5-3i,-5+7i,-5+5i,-5+1i,-5+3i, ...
      -1-7i,-1-5i,-1-1i,-1-3i,-1+7i,-1+5i,-1+1i,-1+3i, ...
      -3-7i,-3-5i,-3-1i,-3-3i,-3+7i,-3+5i,-3+1i,-3+3i, ...
      +7-7i,+7-5i,+7-1i,+7-3i,+7+7i,+7+5i,+7+1i,+7+3i, ...
      +5-7i,+5-5i,+5-1i,+5-3i,+5+7i,+5+5i,+5+1i,+5+3i, ...
      +1-7i,+1-5i,+1-1i,+1-3i,+1+7i,+1+5i,+1+1i,+1+3i, ...
      +3-7i,+3-5i,+3-1i,-3-3i,+3+7i,+3+5i,+3+1i,+3+3i ];
end

% normalize the constellation to have unit average energy for fair comparison
constellation_energy = mean(abs(constellation_symbols).^2);  % par.constellation_energy: compute average symbol energy
constellation_symbols = constellation_symbols / sqrt(constellation_energy);  % normalize symbols
normalized_energy = mean(abs(constellation_symbols).^2);  % par.Es: verify normalized energy is 1

% precompute bit-to-symbol mapping for efficient decoding
bits_per_symbol = log2(length(constellation_symbols));  % par.Q: bits per symbol (e.g., 2 for QPSK)
bit_mappings = de2bi(0:length(constellation_symbols)-1, bits_per_symbol, 'left-msb');  % par.bits: binary representations of symbol indices

% track simulation time
time_elapsed = 0;

% intermediate results
y_jammerless_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_jl_f
y_jammer_compliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_oj_comp_f
y_jammer_noncompliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_oj_noncomp_f
y_compliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_comp_f
y_noncompliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_noncomp_f


% Initialize accumulators for averaging for received signal
avg_y_jammerless = zeros(num_receive_antennas, num_used_subcarriers, num_ofdm_symbols);
avg_y_jammer_compliant = zeros(num_receive_antennas, num_used_subcarriers, num_ofdm_symbols);
avg_y_jammer_noncompliant = zeros(num_receive_antennas, num_used_subcarriers, num_ofdm_symbols);


% results
jammer_compliant_freq_domain = zeros(num_trials, num_used_subcarriers,num_receive_antennas, num_ofdm_symbols); % Y_oj_comp
jammer_noncompliant_freq_domain = zeros(num_trials, num_used_subcarriers,num_receive_antennas, num_ofdm_symbols); % Y_oj_noncomp

mse_jammerless = zeros(1, length(snr_dB_list)); % MSE_jl
mse_compliant = zeros(1, length(snr_dB_list)); % MSE_comp
mse_noncompliant = zeros(1, length(snr_dB_list)); % MSE_noncomp

res.BER_jammerless = zeros(1, length(snr_dB_list)); % res.BER_jl
res.BER_compliant = zeros(1, length(snr_dB_list)); % res.BER_comp
res.BER_noncompliant = zeros(1, length(snr_dB_list)); % res.BER_noncomp
res.BER_projections = zeros(num_channel_taps, length(snr_dB_list)); % res.BER_projs

res.spatial_interference_distribution = zeros(num_receive_antennas,num_used_subcarriers);

% Initialize variables for per-trial errors to compute success rate of mitigating illegal users/channel taps
errors_jammerless_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_jl_per_trial
errors_compliant_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_comp_per_trial
errors_noncompliant_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_noncomp_per_trial

% generate bit stream
bits = randi([0 1],num_trials,num_ofdm_symbols,num_transmit_antennas,bits_per_symbol,num_used_subcarriers); % bits
indices = zeros(num_trials, num_ofdm_symbols, num_transmit_antennas,num_used_subcarriers); % idx

%% 

% trials loop
% Corresponds to Section III (Jammer Modeling and Interference Analysis): Monte-Carlo simulation loop for channel realizations and jammer signal generation.
tic
for trial_index=1:num_trials


  % generate jammer's channel in the time domain
  % Corresponds to Section II (System Model): Rayleigh fading channel generation.
  jammer_channel_time = sqrt(0.5)*randn(num_receive_antennas,num_channel_taps) + 1j*sqrt(0.5)*randn(num_receive_antennas,num_channel_taps);  % j_
  jammer_channel_freq = (1/sqrt(num_subcarriers))*fft(jammer_channel_time,num_subcarriers,2);  % j_f

  % generate the UEs' channel in the time domain
  user_channel_time = sqrt(0.5)*randn(num_receive_antennas,num_transmit_antennas,num_channel_taps) + 1j*sqrt(0.5)*randn(num_receive_antennas,num_transmit_antennas,num_channel_taps);  % H_
  user_channel_freq = (1/sqrt(num_subcarriers))*fft(user_channel_time,num_subcarriers,3); % H_f
  % E[||H_f(:,:,i)||_F^2] = B*U*L/N_sc for all i=1,...,N_sc
  % expected receive signal energy per subcarrier is E_s*B*U*L/N_sc = B*U*L/N_sc

  % set jammer power: either random per trial or fixed
  if random_jammer_power_flag
    jammer_power_linear = num_transmit_antennas*10^(jammer_power_dB*rand()/10);  % par.rho
  else
    jammer_power_linear = num_transmit_antennas*10^(jammer_power_dB/10);  % par.rho
  end


  % loop over SNR values to simulate different noise levels
  for snr_index = 1:length(snr_dB_list)
    
    noise_variance = num_channel_taps*num_transmit_antennas*10^(-snr_dB_list(snr_index)/10);  % par.N0
    
    % loop over OFDM symbols for temporal averaging
    for ofdm_symbol_index=1:num_ofdm_symbols
  
      %%% generate transmit signals
      % Corresponds to Section III (Jammer Modeling): Gener ation of compliant and noncompliant jammer signals, and UE signals.
        
      % generate jammer's transmit signal in frequency domain (OFDM-compliant)
      jammer_signal_freq = sqrt(0.5)*randn(1,num_subcarriers)+ 1j*sqrt(0.5)*randn(1,num_subcarriers);  % generates random bits in frequency domain, Gaussian noise
      jammer_signal_time_no_cp = sqrt(num_subcarriers)*ifft(jammer_signal_freq);% converts frequency to time domain, ifft (Inverse Fast Fourier Transform)
      jammer_signal_time = [jammer_signal_time_no_cp(:,num_subcarriers-cyclic_prefix_length+1:num_subcarriers), jammer_signal_time_no_cp];  % add cp
      jammer_signal_time = sqrt(jammer_power_linear)*jammer_signal_time;  % scale jammer power compared to jammerless
  
      % generate jammer's transmit signal in time domain (OFDM-noncompliant)
      jammer_signal_time_noncomp = sqrt(0.5)*randn(1,num_subcarriers+cyclic_prefix_length)+ 1j*sqrt(0.5)*randn(1,num_subcarriers+cyclic_prefix_length); % generates random noise in time  domain
      jammer_signal_time_noncomp = sqrt(jammer_power_linear)*jammer_signal_time_noncomp;  % scale jammer power compared to jammerless       

      % OFDM-compliant TVWS operator: Generation of legitimate user signal in TVWS using OFDM
      % generate UEs' transmit signal in frequency domain (OFDM-compliant)
       for tone_index=1:num_used_subcarriers
            bits_slice = squeeze(bits(trial_index, ofdm_symbol_index, :, :, tone_index)); % size [2 1]
            bits_slice = bits_slice.'; % transpose to [1 2]
            indices(trial_index, ofdm_symbol_index, 1, tone_index) = bi2de(bits_slice, 'left-msb') + 1;
       end
      symbols_used_tones = constellation_symbols(squeeze(indices(trial_index,ofdm_symbol_index,:,:))); % S_f_used_tones
      symbols_freq = zeros(num_transmit_antennas, num_subcarriers);  % S_f
      % Subcarrier assignment: Assign the modulated symbols to the designated used subcarriers in the frequency domain
      symbols_freq(:,used_subcarrier_indices) = symbols_used_tones; % assign symbols only to used subcarriers
      symbols_time_no_cp = sqrt(num_subcarriers)*ifft(symbols_freq,num_subcarriers,2);  % S_no_cp
      symbols_time = [symbols_time_no_cp(:,num_subcarriers-cyclic_prefix_length+1:num_subcarriers), symbols_time_no_cp];  % S

    
      %%% generate receive signals
      % Threat DB 
      receive_signal_legitimate_TVWSO = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);% y_jl 8 x 64+16+4-1
      receive_signal_jammer_compliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);     % y_oj_comp
      receive_signal_jammer_noncompliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);  % y_oj_noncomp
      receive_signal_compliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);        % y_comp
      receive_signal_noncompliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);     % y_noncomp


      % convolve channels with transmit signals to simulate received signals
      for receive_ant_index=1:num_receive_antennas
        for transmit_ant_index=1:num_transmit_antennas %1->2
          receive_signal_legitimate_TVWSO(receive_ant_index,:) = receive_signal_legitimate_TVWSO(receive_ant_index,:) + conv(squeeze(user_channel_time(receive_ant_index,transmit_ant_index,:)),symbols_time(transmit_ant_index,:));  % sum of convolutions for each UE 
                %receive_signal_legitimate_TVWSO(1=>8,83) = 
        end
        receive_signal_jammer_compliant(receive_ant_index,:) = conv(jammer_channel_time(receive_ant_index,:),jammer_signal_time);  % jammer convolution compliant
        receive_signal_jammer_noncompliant(receive_ant_index,:) = conv(jammer_channel_time(receive_ant_index,:),jammer_signal_time_noncomp);  % jammer convolution noncompliant
      end
      receive_signal_compliant = receive_signal_legitimate_TVWSO + receive_signal_jammer_compliant;  % total received signal with compliant jammer
      receive_signal_noncompliant = receive_signal_legitimate_TVWSO + receive_signal_jammer_noncompliant;  % total received signal with noncompliant jammer


      %%% generate noise in the time domain
      noise_time = sqrt(0.5)*randn(num_receive_antennas,num_subcarriers)+ 1j*sqrt(0.5)*randn(num_receive_antennas,num_subcarriers);  % N
      noise_time = sqrt(noise_variance)*noise_time;  % scale noise by noise variance

      %%% truncate signals in time-domain to remove cyclic prefix and channel reverberation
      receive_signal_legitimate_TVWSO_truncated = receive_signal_legitimate_TVWSO(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % jammerless signal + noise
      receive_signal_jammer_compliant_truncated = receive_signal_jammer_compliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers); % jammer only, no noise
      receive_signal_jammer_noncompliant_truncated = receive_signal_jammer_noncompliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers); % jammer only, no noise
      receive_signal_compliant_truncated = receive_signal_compliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % full signal + noise
      receive_signal_noncompliant_truncated = receive_signal_noncompliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % full signal + noise


      %%% convert to frequency domain
      y_jammerless_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_legitimate_TVWSO_truncated, num_subcarriers, 2);  % jammerless frequency domain
      y_jammer_compliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_jammer_compliant_truncated, num_subcarriers, 2);  % jammer compliant freq domain
      y_jammer_noncompliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_jammer_noncompliant_truncated, num_subcarriers, 2);  % jammer noncompliant freq domain
      y_compliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_compliant_truncated, num_subcarriers, 2);  % full compliant freq domain
      y_noncompliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_noncompliant_truncated, num_subcarriers, 2);  % full noncompliant freq domain


      %%% remove subcarriers that are not used
      y_jammerless_frequency_tones = y_jammerless_frequency(:,used_subcarrier_indices,:);  % select used subcarriers only
      y_jammer_compliant_frequency_tones = y_jammer_compliant_frequency(:,used_subcarrier_indices,:);
      y_jammer_noncompliant_frequency_tones = y_jammer_noncompliant_frequency(:,used_subcarrier_indices,:);
      y_compliant_frequency_tones = y_compliant_frequency(:,used_subcarrier_indices,:);
      y_noncompliant_frequency_tones = y_noncompliant_frequency(:,used_subcarrier_indices,:);


      user_channel_frequency_tones = user_channel_freq(:,:,used_subcarrier_indices);  % channel frequency response for used subcarriers
    
      %%% collect jammer statistics for later analysis
      for tone_index=1:num_used_subcarriers
        jammer_compliant_freq_domain(trial_index,tone_index,:,ofdm_symbol_index) = y_jammer_compliant_frequency_tones(:,tone_index,ofdm_symbol_index);  % store jammer compliant freq domain
        jammer_noncompliant_freq_domain(trial_index,tone_index,:,ofdm_symbol_index) = y_jammer_noncompliant_frequency_tones(:,tone_index,ofdm_symbol_index);  % store jammer noncompliant freq domain   
      end

    end % iterate over OFDM symbols with same channel realization


    for tone_index=1:num_used_subcarriers
  
      %%% extract jammer statistics
      % Corresponds to Section IV (Mitigation): SVD for subspace estimation.
      % SVD: What spatial direction the jammer energy points to (U)? & How strong it is in each direction (S)?
      [U_compliant,S_compliant,~] = svd(squeeze(jammer_compliant_freq_domain(trial_index,tone_index,:,:)));  % singular value decomposition of jammer compliant
      [U_noncompliant,S_noncompliant,~] = svd(squeeze(jammer_noncompliant_freq_domain(trial_index,tone_index,:,:)));  % SVD of jammer noncompliant
      singular_values = diag(S_noncompliant);
      dominant_sv = singular_values(1);  % largest singular value
      res.spatial_interference_distribution(:, tone_index) = res.spatial_interference_distribution(:, tone_index) + dominant_sv;

      % form best rank-(num_receive_antennas-1) projectors: those that remove most of the
      % jammer interference per subcarrier
      % Corresponds to Section IV (Mitigation): Nulling matrices.
      jammer_estimate_compliant = U_compliant(:,1);  % dominant jammer subspace vector compliant
      % Mitigation: Projection matrix to nullify the compliant jammer subspace, mitigating its effect on TVWS operator
      projection_matrix_compliant = eye(num_receive_antennas) - jammer_estimate_compliant*jammer_estimate_compliant';  % projection matrix to null jammer compliant
      jammer_estimate_noncompliant = U_noncompliant(:,1);  % dominant jammer subspace vector noncompliant
      projection_matrix_noncompliant = eye(num_receive_antennas) - jammer_estimate_noncompliant*jammer_estimate_noncompliant';  % projection matrix to null jammer noncompliant

      % projectors = zeros(num_channel_taps,num_receive_antennas,num_receive_antennas);  % initialize multiple projectors for different nulling dimensions
      % for b=1:num_channel_taps
      %   projectors(b,:,:) = eye(num_receive_antennas) - U_noncompliant(:,1:b)*U_noncompliant(:,1:b)';  % projector nulling b jammer subspace vectors
      % end
      projectors = repmat(eye(num_receive_antennas), [num_channel_taps, 1, 1]); %no-op projection in SISO
  
      %%% detect signals using zero-forcing (and, in some cases, jammer mitigation)
      % Corresponds to Section IV (Mitigation): Zero-forcing detection with/without nulling., recover the transmitted symbol    
      % symbols_estimate_jammerless = (sqrt(num_subcarriers)*(user_channel_frequency_tones(:,:,tone_index)))\squeeze(y_jammerless_frequency_tones(:,tone_index,:));  % zero-forcing estimate jammerles
      symbols_estimate_jammerless = y_jammerless_frequency_tones(:,tone_index,:) ./ user_channel_frequency_tones(:,:,tone_index);

      % Mitigated TVWS operator symbol estimation using projection matrix to suppress compliant jammer interference
      symbols_estimate_compliant = (sqrt(num_subcarriers)*(projection_matrix_compliant*squeeze(user_channel_frequency_tones(:,:,tone_index))))\(projection_matrix_compliant*squeeze(y_compliant_frequency_tones(:,tone_index,:)));  % zero-forcing with jammer mitigation compliant
      symbols_estimate_noncompliant = (sqrt(num_subcarriers)*(projection_matrix_noncompliant*squeeze(user_channel_frequency_tones(:,:,tone_index))))\(projection_matrix_noncompliant*squeeze(y_noncompliant_frequency_tones(:,tone_index,:)));  % zero-forcing with jammer mitigation noncompliant
      symbols_estimate_projections = zeros(num_receive_antennas,num_transmit_antennas,num_ofdm_symbols);  % initialize estimates for multiple projectors
      for b=1:num_channel_taps
        symbols_estimate_projections(b,:,:) = (sqrt(num_subcarriers)*(squeeze(projectors(b,:,:))*squeeze(user_channel_frequency_tones(:,:,tone_index))))\(squeeze(projectors(b,:,:))*squeeze(y_noncompliant_frequency_tones(:,tone_index,:)));  % zero-forcing with multiple nulling projectors
      end
        
           % -- compute bit outputs by minimum distance decoding
      % Corresponds to Section IV (Mitigation): Decoding.
      symbols_estimate_jammerless_vector = reshape(symbols_estimate_jammerless, [num_ofdm_symbols*num_transmit_antennas,1]);  % vectorize estimated symbols
      [~,indices_hat_vector] = min(abs(symbols_estimate_jammerless_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);  % find closest constellation points
      indices_hat_jammerless = indices_hat_vector;  % indices [50,1]
      bits_hat_jammerless = bit_mappings(indices_hat_jammerless,:);  % map indices to bits [50,2]

      symbols_estimate_compliant_vector = reshape(symbols_estimate_compliant, [num_ofdm_symbols*num_transmit_antennas,1]);
      [~,indices_hat_vector] = min(abs(symbols_estimate_compliant_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
      indices_hat_compliant = indices_hat_vector;
      bits_hat_compliant = bit_mappings(indices_hat_compliant,:);  % [50,2]

      symbols_estimate_noncompliant_vector = reshape(symbols_estimate_noncompliant, [num_ofdm_symbols*num_transmit_antennas,1]);
      [~,indices_hat_vector] = min(abs(symbols_estimate_noncompliant_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
      indices_hat_noncompliant = indices_hat_vector;
      bits_hat_noncompliant = bit_mappings(indices_hat_noncompliant,:);  % [50,2]

      bits_hat_projections = zeros(num_channel_taps, num_ofdm_symbols, num_transmit_antennas, bits_per_symbol);
      for b=1:num_channel_taps
        symbols_estimate_vector = reshape(symbols_estimate_projections(b,:,:), [num_ofdm_symbols*num_transmit_antennas,1]);
        [~,indices_hat_vector] = min(abs(symbols_estimate_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
        indices_hat = reshape(indices_hat_vector, [num_ofdm_symbols, num_transmit_antennas]);
        bits_hat_projections(b,:,:,:) = reshape(bit_mappings(indices_hat,:), [num_ofdm_symbols, num_transmit_antennas, bits_per_symbol]);
      end
      

      %gian marti BER
      % -- compute error metrics by comparing estimated bits to true bits
      % Corresponds to Section IV (Mitigation): BER calculation.
      bit_tensor = squeeze(bits(trial_index,:,:,:,tone_index));  % extract bits for current trial and subcarrier [50,2]
      bit_tensor = permute(bit_tensor,[2 1]);  % reorder dimensions for comparison [2,50]
      true_bits = bit_tensor(:,:)';  % flatten to 2D matrix [50,2]
      % bits_hat_jammerless is already 50x2 after reshape and transpose
      % bits_hat_compliant is already 50x2 after reshape and transpose
      % bits_hat_noncompliant is already 50x2 after reshape and transpose

      res.BER_jammerless(snr_index) = res.BER_jammerless(snr_index) + sum(sum(true_bits~=bits_hat_jammerless));  % count bit errors jammerless
      res.BER_compliant(snr_index) = res.BER_compliant(snr_index) + sum(sum(true_bits~=bits_hat_compliant));  % count bit errors jammer compliant
      res.BER_noncompliant(snr_index) = res.BER_noncompliant(snr_index) + sum(sum(true_bits~=bits_hat_noncompliant));  % count bit errors jammer noncompliant
      for b=1:num_channel_taps
        bits_hat_proj = squeeze(bits_hat_projections(b,:,:));
        bits_hat_proj = reshape(bits_hat_proj, [num_transmit_antennas, num_ofdm_symbols, bits_per_symbol]);
        bits_hat_proj = permute(bits_hat_proj, [3 2 1]);
        bits_hat_proj = bits_hat_proj(:,:)';
        res.BER_projections(b,snr_index) = res.BER_projections(b,snr_index) + sum(sum(true_bits~=bits_hat_proj));  % count bit errors for each nulling dimension
      end


    end
%% 

  end % iterate over SNRs

  % -- keep track of simulation time and print estimated remaining time
  if(print_messages_flag)
    if toc>10
      time = toc;
      time_elapsed = time_elapsed + time;
        fprintf('estimated remaining simulation time: %3.0f min.\n', ...
          time_elapsed*(num_trials/trial_index-1)/60);
      tic
    end
  end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         % 
         if mod(trial_index, 1) == 0 % Update plot every 5 trials

            % Plot frequency domain used subcarriers signals (magnitude) for antenna 2 and last OFDM symbol
            %figure(11)
            subplot
            app.UIAxes
            app.UIAxes5
            app.UIAxes6
            %app.UIAxes1_2
            %app.UIAxes5_2
            %app.UIAxes6_2
            clf
            antenna_idx = 1;
            ofdm_symbol_idx = num_ofdm_symbols; % last OFDM symbol

            %subplot(5,1,1)
            plot(app.UIAxes, used_subcarrier_indices, real(squeeze(y_jammerless_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes, [-100 100])
            title(app.UIAxes, 'Legitimate TVWSO Frequency Tones')
            xlabel(app.UIAxes, 'Subcarrier Index')
            ylabel(app.UIAxes, 'Magnitude')
            plot(app.UIAxes1_2, used_subcarrier_indices, real(squeeze(y_jammerless_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes1_2, [-100 100])
            title(app.UIAxes1_2, 'Legitimate TVWSO Frequency Tones')
            xlabel(app.UIAxes1_2, 'Subcarrier Index')
            ylabel(app.UIAxes1_2, 'Magnitude')

            %subplot(5,1,2)
            plot(app.UIAxes5, used_subcarrier_indices, real(squeeze(y_jammer_compliant_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes5, [-100 100])
            title(app.UIAxes5, 'OFDM-compliant Illegal TVWSO Frequency Tones ')
            xlabel(app.UIAxes5, 'Subcarrier Index')
            ylabel(app.UIAxes5, 'Magnitude')
            plot(app.UIAxes5_2, used_subcarrier_indices, real(squeeze(y_jammer_compliant_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes5_2, [-100 100])
            title(app.UIAxes5_2, 'OFDM-compliant Illegal TVWSO Frequency Tones ')
            xlabel(app.UIAxes5_2, 'Subcarrier Index')
            ylabel(app.UIAxes5_2, 'Magnitude')

            %subplot(5,1,3)
            plot(app.UIAxes6, used_subcarrier_indices, real(squeeze(y_jammer_noncompliant_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes6, [-100 100])
            title(app.UIAxes6, 'Cyclic prefix-violating Illegal TVWSO Frequency Tones ')
            xlabel(app.UIAxes6, 'Subcarrier Index')
            ylabel(app.UIAxes6, 'Magnitude')
            plot(app.UIAxes6_2, used_subcarrier_indices, real(squeeze(y_jammer_noncompliant_frequency_tones(antenna_idx,:,ofdm_symbol_idx))))
            ylim(app.UIAxes6_2, [-100 100])
            title(app.UIAxes6_2, 'Cyclic prefix-violating Illegal TVWSO Frequency Tones ')
            xlabel(app.UIAxes6_2, 'Subcarrier Index')
            ylabel(app.UIAxes6_2, 'Magnitude')

            %sgtitle(['Received Signals at Used Subcarriers ( OFDM Symbol ', num2str(ofdm_symbol_idx), ') at Trial ', num2str(trial_index)]) % Add a super title with trial number
            app.trialLabel.Text = sprintf('Received Signals Characterization (OFDM Symbol %d) at Trial %d', ofdm_symbol_idx, trial_index);
            app.trialLabel_2.Text = sprintf('Received Signals Characterization (OFDM Symbol %d) at Trial %d', ofdm_symbol_idx, trial_index);
            drawnow % Force update of the figure for live view

         end

      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

  % Accumulate signals across trials
    avg_y_jammerless = avg_y_jammerless + y_jammerless_frequency_tones;
    avg_y_jammer_compliant = avg_y_jammer_compliant + y_jammer_compliant_frequency_tones;
    avg_y_jammer_noncompliant = avg_y_jammer_noncompliant + y_jammer_noncompliant_frequency_tones;

end % iterate over MC trials


% normalize results
% Corresponds to Section V (Results): Averaging BER over trials and subcarriers.
res.BER_jammerless = res.BER_jammerless/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
res.BER_compliant = res.BER_compliant/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
res.BER_noncompliant = res.BER_noncompliant/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
res.BER_projections = res.BER_projections/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);

%% Export BER results for external verification
export_table = table(snr_dB_list(:), ...
    res.BER_jammerless(:), ...
    res.BER_compliant(:), ...
    res.BER_noncompliant(:), ...
    'VariableNames', {'SNR_dB', 'BER_legit', 'BER_compliant', 'BER_noncompliant'});

writetable(export_table, 'TDRR_BER_export.csv');
fprintf('Exported BER results to TDRR_BER_export.csv\n');

res.spatial_interference_distribution = res.spatial_interference_distribution./sum(res.spatial_interference_distribution,1);
res.spatial_interference_distribution_sc_std = std(res.spatial_interference_distribution.');
res.spatial_interference_distribution_sc_avg = mean(res.spatial_interference_distribution.');

avg_y_jammerless = avg_y_jammerless / num_trials;
avg_y_jammer_compliant = avg_y_jammer_compliant / num_trials;
avg_y_jammer_noncompliant = avg_y_jammer_noncompliant / num_trials;

%% Export Null-dimension projection BER results for external verification
export_table_proj = table(snr_dB_list(:), ...
    res.BER_projections(1,:)', ...
    res.BER_projections(2,:)', ...
    'VariableNames', {'SNR_dB', 'BER_Null1Dim', 'BER_Null2Dim'});

writetable(export_table_proj, 'TDRR_BER_projections_export.csv');
fprintf('Exported projection BER results to TDRR_BER_projections_export.csv\n');


%%% plot results
% Corresponds to Section V (Results): Visualization of BER performance and interference distribution.
if plot_flag
   
  %figure(1)
  semilogy(app.UIAxes7, snr_dB_list, res.BER_jammerless, 'LineWidth', 2.5)
  hold (app.UIAxes7, 'off')
  grid(app.UIAxes7, 'on')
  axis(app.UIAxes7, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel(app.UIAxes7, 'average SNR per receive antenna [dB]','FontSize',12)
  ylabel(app.UIAxes7, 'uncoded bit error-rate (BER)','FontSize',12)
  legend(app.UIAxes7, "Legitimate TVWSO",'FontSize',12,'Interpreter','none')
  set(app.UIAxes7,'FontSize',12)
  %set(app.UIAxes7,'position',[10,10,400,300])

  %figure(2)
  semilogy(app.UIAxes8, snr_dB_list, res.BER_jammerless, 'LineWidth', 2.5)
  hold (app.UIAxes8, 'on')
  semilogy(app.UIAxes8, snr_dB_list, res.BER_compliant, 'LineWidth', 2.5)
  hold (app.UIAxes8, 'off')
  grid(app.UIAxes8, 'on')
  axis(app.UIAxes8, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel(app.UIAxes8,'average SNR per receive antenna [dB]','FontSize',12)
  ylabel(app.UIAxes8, 'uncoded bit error-rate (BER)','FontSize',12)
  legend(app.UIAxes8, ["Legitimate TVWSO", "OFDM-compliant Illegitimate TVWSO"],'FontSize',12,'Interpreter','none')
  set(app.UIAxes8,'FontSize',12)
  %set(app.UIAxes8,'position',[10,10,400,300])

  %figure(3)
  semilogy(app.UIAxes9, snr_dB_list, res.BER_jammerless, 'LineWidth', 2.5)
  hold (app.UIAxes9, 'on')
  semilogy(app.UIAxes9, snr_dB_list, res.BER_noncompliant, 'LineWidth', 2.5)
  hold (app.UIAxes9, 'off')
  grid(app.UIAxes9, 'on')
  axis(app.UIAxes9, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel(app.UIAxes9, 'average SNR per receive antenna [dB]','FontSize',12)
  ylabel(app.UIAxes9, 'uncoded bit error-rate (BER)','FontSize',12)
  legend(app.UIAxes9, ["Legitimate TVWS", "Cyclic prefix-violating Illegal TVWSO"],'FontSize',12,'Interpreter','none')
  set(app.UIAxes9,'FontSize',12)
  %set(app.UIAxes9,'position',[10,10,400,300])

  semilogy(app.UIAxes9_2, snr_dB_list, res.BER_jammerless, 'LineWidth', 2.5)
  hold (app.UIAxes9_2, 'on')
  semilogy(app.UIAxes9_2, snr_dB_list, res.BER_noncompliant, 'LineWidth', 2.5)
  hold (app.UIAxes9_2, 'off')
  grid(app.UIAxes9_2, 'on')
  axis(app.UIAxes9_2, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel(app.UIAxes9_2, 'average SNR per receive antenna [dB]','FontSize',12)
  ylabel(app.UIAxes9_2, 'uncoded bit error-rate','FontSize',12)
  %legend(app.UIAxes9_2, ["Legitimate TVWS", "Cyclic prefix-violating Illegal TVWSO"],'FontSize',12,'Interpreter','none')
  set(app.UIAxes9_2,'FontSize',12)
  %set(app.UIAxes9,'position',[10,10,400,300])

  %figure(4)
  hold (app.UIAxes11, 'on')
  
  bar(app.UIAxes11, 1:num_receive_antennas,res.spatial_interference_distribution_sc_avg, 'FaceColor', [0.2 0.6 0.8]);  % optional color)
  
  er = errorbar(app.UIAxes11, ...
        1:num_receive_antennas, ...
        res.spatial_interference_distribution_sc_avg, ...
        2*res.spatial_interference_distribution_sc_std, ...
        2*res.spatial_interference_distribution_sc_std, ...
        'k', 'LineStyle', 'none', 'LineWidth', 1.2);

  hold(app.UIAxes11, 'off');

  %er.Color = [0 0 0];
  %er.LineStyle = 'none';
  %hold (app.UIAxes11, 'off')
  axis(app.UIAxes11, [0.25 num_receive_antennas+0.75 0 1])
  grid (app.UIAxes11, 'on')
  xlabel(app.UIAxes11, 'ordered spatial dimension index')
  ylabel(app.UIAxes11, 'fraction of receive interference')
  set(app.UIAxes11, 'FontSize', 12);
  %set(gcf,'position',[10,10,400,300])

  hold (app.UIAxes11_2, 'on')
  
  bar(app.UIAxes11_2, 1:num_receive_antennas,res.spatial_interference_distribution_sc_avg, 'FaceColor', [0.2 0.6 0.8]);  % optional color)
  
  er = errorbar(app.UIAxes11_2, ...
        1:num_receive_antennas, ...
        res.spatial_interference_distribution_sc_avg, ...
        2*res.spatial_interference_distribution_sc_std, ...
        2*res.spatial_interference_distribution_sc_std, ...
        'k', 'LineStyle', 'none', 'LineWidth', 1.2);

  hold(app.UIAxes11_2, 'off');

  %er.Color = [0 0 0];
  %er.LineStyle = 'none';
  %hold (app.UIAxes11_2, 'off')
  axis(app.UIAxes11_2, [0.25 num_receive_antennas+0.75 0 1])
  grid (app.UIAxes11_2, 'on')
  xlabel(app.UIAxes11_2, 'ordered spatial dimension index')
  ylabel(app.UIAxes11_2, 'fraction of receive interference')
  set(app.UIAxes11_2, 'FontSize', 12);
  %set(gcf,'position',[10,10,400,300])

  %figure(5)
    clf
    
    % Plot jammerless baseline
    semilogy(app.UIAxes10, snr_dB_list, res.BER_jammerless, 'k--', 'LineWidth', 2.5)
    hold(app.UIAxes10, 'on');
    
    % Plot projected cases (CP-violating jammer with nulling)
    colors = lines(num_channel_taps); % MATLAB color map for consistency
    for b = 1:num_channel_taps
    semilogy(app.UIAxes10, snr_dB_list, res.BER_projections(b,:), 'LineWidth', 2.5, 'Color', colors(b,:))
    end
    
    grid(app.UIAxes10, 'on');
    axis(app.UIAxes10, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
    xlabel(app.UIAxes10, 'Average SNR per receive antenna [dB]', 'FontSize', 12)
    ylabel(app.UIAxes10, 'Uncoded Bit Error Rate (BER)', 'FontSize', 12)
    
    % ----- Legend setup -----
    numbers = cell(1, num_channel_taps + 1);
    numbers{1} = "Legitimate TVWSO (Jammerless)";
    for b = 1:num_channel_taps
    numbers{b+1} = "CP-violating Jammer: Null " + num2str(b) + " Dimension(s)";
    end
    legend(app.UIAxes10, numbers, 'FontSize', 11, 'Interpreter', 'none', 'Location', 'southwest')
    
    set(app.UIAxes10, 'FontSize', 12)
    %set(app.UIAxes10, 'Position', [10, 10, 600, 400])
    title(app.UIAxes10, 'BER vs. SNR for Legitimate and Projected CP-violating Cases')

    clf
    
    % Plot jammerless baseline
    semilogy(app.UIAxes10_2, snr_dB_list, res.BER_jammerless, 'k--', 'LineWidth', 2.5)
    hold(app.UIAxes10_2, 'on');
    
    % Plot projected cases (CP-violating jammer with nulling)
    colors = lines(num_channel_taps); % MATLAB color map for consistency
    for b = 1:num_channel_taps
    semilogy(app.UIAxes10_2, snr_dB_list, res.BER_projections(b,:), 'LineWidth', 2.5, 'Color', colors(b,:))
    end
    
    grid(app.UIAxes10_2, 'on');
    axis(app.UIAxes10_2, [min(snr_dB_list) max(snr_dB_list) 1e-4 1])
    xlabel(app.UIAxes10_2, 'Average SNR per receive antenna [dB]', 'FontSize', 12)
    ylabel(app.UIAxes10_2, 'Uncoded Bit Error Rate', 'FontSize', 12)
    
    % ----- Legend setup -----
    numbers = cell(1, num_channel_taps + 1);
    numbers{1} = "Legitimate TVWSO (Jammerless)";
    for b = 1:num_channel_taps
    numbers{b+1} = "CP-violating Jammer: Null " + num2str(b) + " Dimension(s)";
    end
    %legend(app.UIAxes10_2, numbers, 'FontSize', 11, 'Interpreter', 'none', 'Location', 'southwest')
    
    set(app.UIAxes10_2, 'FontSize', 12)
    %set(app.UIAxes10, 'Position', [10, 10, 600, 400])
    title(app.UIAxes10_2, 'BER vs. SNR for Legitimate and Projected CP-violating Cases')

    % Plot jammer statistics
    %figure(6)
    % Average magnitude over trials and OFDM symbols for compliant jammer
    jammer_compliant_avg = mean(mean(abs(jammer_compliant_freq_domain),4),1); % Average over trials and OFDM symbols
    %subplot(2,1,1)
    imagesc(app.UIAxes3, squeeze(jammer_compliant_avg))
    title(app.UIAxes3, ' Compliant Frequency Domain Magnitude (Avg over Trials and OFDM Symbols)')
    xlabel(app.UIAxes3, 'Receive Antenna')
    ylabel(app.UIAxes3, 'Subcarrier Index')
    colorbar(app.UIAxes3)
    jammer_noncompliant_avg = mean(mean(abs(jammer_noncompliant_freq_domain),4),1); % Average over trials and OFDM symbols
    
    %subplot(2,1,2)
    imagesc(app.UIAxes4_2, squeeze(jammer_noncompliant_avg))
    title(app.UIAxes4_2, 'Noncompliant Freq. Domain Magnitude')
    xlabel(app.UIAxes4_2, 'Receive Antenna')
    ylabel(app.UIAxes4_2, 'Subcarrier Index')

    imagesc(app.UIAxes4, squeeze(jammer_noncompliant_avg))
    title(app.UIAxes4, 'Noncompliant Frequency Domain Magnitude (Avg over Trials and OFDM Symbols)')
    xlabel(app.UIAxes4, 'Receive Antenna')
    ylabel(app.UIAxes4, 'Subcarrier Index')
    colorbar(app.UIAxes4)
    %set(gcf,'position',[10,10,600,400])
    
    % Plot frequency domain average signals for last OFDM symbol
    antenna_idx = 1;
    ofdm_symbol_idx = num_ofdm_symbols; % last OFDM symbol


    %figure(7)
    %subplot(3,1,1)
    %plot(used_subcarrier_indices, real(squeeze(avg_y_jammerless(antenna_idx,:,ofdm_symbol_idx))))
    %ylim([-100 100])
    %title('Average Legitimate TVWSO')
    %xlabel('Subcarrier Index')
    %ylabel('Magnitude')
    %subplot(3,1,2)
    %plot(used_subcarrier_indices, real(squeeze(avg_y_jammer_compliant(antenna_idx,:,ofdm_symbol_idx))))
    %ylim([-100 100])
    %title('Average OFDM-compliant Illegal TVWSO Frequency Tones')
    %xlabel('Subcarrier Index')
    %ylabel('Magnitude')
    %subplot(3,1,3)
    %plot(used_subcarrier_indices, real(squeeze(avg_y_jammer_noncompliant(antenna_idx,:,ofdm_symbol_idx))))
    %ylim([-100 100])
    %title('Average Cyclic prefix-violating Illegal TVWSO Frequency Tones')
    %xlabel('Subcarrier Index')
    %ylabel('Magnitude')
    % Super title for the figure
    %sgtitle(['Average Received Signals at Used Subcarriers (OFDM Symbol ', num2str(ofdm_symbol_idx), ') Across ', num2str(num_trials), ' Trials'])
    
    % Figure 1: Average Legitimate TVWS
    %figure(8)
    clf
    plot(app.UIAxes12, used_subcarrier_indices, real(squeeze(avg_y_jammerless(antenna_idx,:,ofdm_symbol_idx))))
    ylim(app.UIAxes12, [-100 100])
    title(app.UIAxes12, 'Average Legitimate TVWS')
    xlabel(app.UIAxes12, 'Subcarrier Index')
    ylabel(app.UIAxes12, 'Magnitude')
    %app.TitleLabel.Text = sprintf('Average Received Signals (Legitimate TVWSO) at Used Subcarriers (OFDM Symbol %d) Across %d Trials', ofdm_symbol_idx, num_trials);
    %sgtitle(app.UIAxes12, ['Average Received Signals (Legitimate TVWSO) at Used Subcarriers (OFDM Symbol ', num2str(ofdm_symbol_idx), ') Across ', num2str(num_trials), ' Trials'])
    
    size(avg_y_jammer_compliant)
    % Figure 2: Average OFDM-compliant Illegitimate TVWS
    %figure(9)
    clf
    plot(app.UIAxes13, used_subcarrier_indices, real(squeeze(avg_y_jammer_compliant(antenna_idx,:,ofdm_symbol_idx))),'LineWidth', 1.5);
    ylim(app.UIAxes13, [-100 100])
    title(app.UIAxes13, 'Average OFDM-compliant Illegitimate TVWS Frequency Tones')
    xlabel(app.UIAxes13, 'Subcarrier Index')
    ylabel(app.UIAxes13, 'Magnitude')
    size(avg_y_jammer_compliant)
    
    %sgtitle(app.UIAxes13, ['Average Received Signals (OFDM-compliant Illegal TVWSO)at Used Subcarriers (OFDM Symbol ', num2str(ofdm_symbol_idx), ') Across ', num2str(num_trials), ' Trials'])
    %app.TitleLabel.Text = sprintf('Average Received Signals (OFDM-compliant Illegal TVWSO) at Used Subcarriers (OFDM Symbol %d) Across %d Trials', ofdm_symbol_idx, num_trials);

    % Figure 3: Average Cyclic prefix-violating Illegitimate TVWS
    %figure(10)
    clf
    plot(app.UIAxes14, used_subcarrier_indices, real(squeeze(avg_y_jammer_noncompliant(antenna_idx,:,ofdm_symbol_idx))),'LineWidth', 1.5);
    ylim(app.UIAxes14, [-100 100])
    title(app.UIAxes14, 'Average Cyclic prefix-violating Illegitimate TVWS Frequency Tones')
    xlabel(app.UIAxes14, 'Subcarrier Index')
    ylabel(app.UIAxes14, 'Magnitude')
    %sgtitle(app.UIAxes14, ['Average Received Signal (Cyclic prefix-violating Illegal TVWSO) at Used Subcarriers (OFDM Symbol ', num2str(ofdm_symbol_idx), ') Across ', num2str(num_trials), ' Trials'])
    %app.TitleLabel1.Text = sprintf('Average Received Signals (Cyclic prefix-violating Illegal TVWSO) at Used Subcarriers (OFDM Symbol %d) Across %d Trials', ofdm_symbol_idx, num_trials);
    size(avg_y_jammer_compliant)

current_dir = pwd;
figures_dir = fullfile(current_dir, 'figures'); 
  
  % Create folder if it doesn't exist
  if ~exist(figures_dir, 'dir')
      mkdir(figures_dir);
  end

  % Find all figure handles
  %figHandles = findall(0, 'Type', 'figure');

   %for i = 1:length(figHandles)
    %  figNum = figHandles(i).Number;
     % filename = fullfile(figures_dir, sprintf('%d.png', figNum));
      %fprintf('Saving Figure %d → %s\n', figNum, filename);

      % --- Add margin & save high quality ---
      % Set figure background to white
      %set(figHandles(i), 'Color', 'white');

      % Adjust paper size slightly larger than figure
      %set(figHandles(i), 'PaperPositionMode', 'auto');
      %pos = get(figHandles(i), 'Position');
      %margin = 50; % pixels of margin
      %set(figHandles(i), 'Position', [pos(1)-margin pos(2)-margin pos(3)+2*margin pos(4)+2*margin]);

      % Save as high-resolution PNG
     % print(figHandles(i), filename, '-dpng', '-r300');
  %end

  %fprintf('All figures saved in folder "%s".\n', figures_dir);
  % 
  % pause(2)  % pause 2 seconds to view the plot
  % close all

end
           
        end

        % Button pushed function: TVWSOperatorButton
        function TVWSOperatorButtonPushed(app, event)
 % --- Make sure simulation is started ---
    if ~app.simulationActive
        msgbox('Please start the simulation first.', 'Notice', 'warn');
        return;
    end

    % --- Initialize randomized situations only once per run ---
     if isempty(app.randomOrder)
         app.situations = {
             'OFDM-compliant', 'Illegal';
             'Cyclic prefix-violating', 'Illegal';
             'Cyclic prefix-violating', 'Illegal';
             'Cyclic prefix-violating', 'Illegal';
             'None', 'Legitimate'
         };

         app.randomOrder = randperm(5);  % randomize order 1–5
         app.situationIndex = 0;         % start index
     end


    % --- Increment to next situation ---
    % app.situationIndex = app.situationIndex + 1;

    % --- Stop if all 5 done ---
    % if app.situationIndex > 5
        % msgbox('You have run all 5 situations. Please click Reset Simulation to start again.', ...
               % 'Simulation Complete');
        % return;
    % end

    % --- Get current randomized situation ---
    % idx = app.randomOrder(app.situationIndex);
    % chosenValue = app.situations{idx, 1};
    % statusText  = app.situations{idx, 2};

    
    for runNum = 1:5

        app.situationIndex = runNum;
    
        % --- Get current randomized situation ---
        idx = app.randomOrder(runNum);
        chosenValue = app.situations{idx, 1};
        statusText  = app.situations{idx, 2};
    
        % --- Update Run Time label ---
        app.RunTimeLabel.Text = sprintf('Run Time: %d', runNum);
    
        drawnow;
    
        % --- Reset indicators ---
        elements = {'otpr1','otpr2','otpr3','otpr4','otpr5','otpr6','otpr7','otpr8','error1','error2','success'};
    
        for i = 1:numel(elements)
            if isprop(app,elements{i})
                app.(elements{i}).Visible = 'off';
            end
        end
    
        pause(0.5);
    
        % --- Detection sequence ---
        app.Lamp.Color = [1 0.65 0];
        pause(1);
    
        if strcmp(statusText,'Illegal')
    
            app.otpr1.Visible = 'on'; pause(0.5);
            app.otpr2.Visible = 'on'; pause(0.5);
            app.otpr4.Visible = 'on'; pause(0.5);
    
            app.error1.Visible = 'on';
            app.Lamp.Color = [1 0 0];
    
        else
    
            app.otpr1.Visible = 'on';
            app.otpr2.Visible = 'on';
            app.otpr3.Visible = 'on'; pause(1);
    
            app.otpr4.Visible = 'on';
            app.otpr5.Visible = 'on';
            app.otpr6.Visible = 'on'; pause(1);
    
            app.otpr7.Visible = 'on';
            app.success.Visible = 'on';
    
            app.Lamp.Color = [0 1 0];
    
        end
    
        % --- Log data ---
        currentDate = datestr(now,'mmmm dd, yyyy');
        currentTime = datestr(now,'HH:MM:SS');
    
        newRow = {num2str(runNum), currentDate, currentTime, statusText};
        app.UITable.Data = [app.UITable.Data; newRow];
    
        % --- Update edit field ---
        fieldName = sprintf('EditField_%d', runNum);
        if isprop(app, fieldName)
            app.(fieldName).Value = chosenValue;
        end
    
        % --- Color row ---
        try
            greenStyle = uistyle('BackgroundColor',[0.8 1 0.8]);
            redStyle = uistyle('BackgroundColor',[1 0.8 0.8]);
    
            if strcmp(statusText,'Legitimate')
                addStyle(app.UITable,greenStyle,'row',size(app.UITable.Data,1));
            else
                addStyle(app.UITable,redStyle,'row',size(app.UITable.Data,1));
                msgbox(sprintf('Threat Detected: %s', chosenValue), 'Notice', 'warn');
            end
        catch
        end
    
        drawnow;
    
        % Wait before next operator
        pause(2);
    
    end
    
    msgbox('All 5 situations have been completed.', 'Complete');

    % --- Update Run Time label ---
    % app.RunTimeLabel.Text = sprintf('Run Time: %d', app.situationIndex);

    % --- Reset all visible lamps or outputs ---
    % elements = {'otpr1','otpr2','otpr3','otpr4','otpr5','otpr6','otpr7','otpr8','error1','error2','success'};
    % for i = 1:numel(elements)
        % if isprop(app, elements{i})
            % app.(elements{i}).Visible = 'off';
        % end
    % end
    % pause(0.3);

    % --- Simulate operator detection ---
    % app.Lamp.Color = [1 0.65 0]; % Orange for estimating
    % pause(1);

    % if strcmp(statusText, 'Illegal')
        % 🔴 Illegal behavior
        % app.otpr1.Visible = 'on'; pause(0.5);
        % app.otpr2.Visible = 'on'; pause(0.5);
        % app.otpr4.Visible = 'on'; pause(0.5);
        % app.error1.Visible = 'on';
        % app.Lamp.Color = [1 0 0];
        % msgbox(sprintf('Threat Detected: %s', chosenValue), 'Notice', 'warn');
    % else
        % 🟢 Legitimate operator
        % app.otpr1.Visible = 'on';
        % app.otpr2.Visible = 'on';
        % app.otpr3.Visible = 'on'; pause(1);
        % app.otpr4.Visible = 'on'; 
        % app.otpr5.Visible = 'on'; 
        % app.otpr6.Visible = 'on'; pause(1);
        % app.otpr7.Visible = 'on'; 
        % app.success.Visible = 'on';
        % app.Lamp.Color = [0 1 0];
    % end

    % --- Log Data to Table ---
    % currentDate = datestr(now, 'mmmm dd, yyyy');
    % currentTime = datestr(now, 'HH:MM:SS');
    % operatorId = num2str(app.situationIndex);

    % newRow = {operatorId, currentDate, currentTime, statusText};
    % app.UITable.Data = [app.UITable.Data; newRow];

    % --- Update corresponding EditField (1–5) ---
    % fieldName = sprintf('EditField_%d', app.situationIndex);
    % if isprop(app, fieldName)
        % app.(fieldName).Value = sprintf('%s', chosenValue);
    % end

    % --- Apply row color style ---
    % try
        % greenStyle = uistyle('BackgroundColor', [0.8 1 0.8]);  % green
        % redStyle   = uistyle('BackgroundColor', [1 0.8 0.8]);  % red

        % if strcmp(statusText, 'Legitimate')
            % addStyle(app.UITable, greenStyle, 'row', size(app.UITable.Data, 1));
        % else
            % addStyle(app.UITable, redStyle, 'row', size(app.UITable.Data, 1));
        % end
    % catch
        % warning('Could not apply row color styling.');
    % end


        end

        % Button pushed function: ResetTVWSOpeatorButton
        function ResetTVWSOpeatorButtonPushed(app, event)
            % Must start first
            if ~app.simulationActive
                uialert(app.UIFigure, 'Please start the simulation first.', 'Notice');
                return;
            end

            app.success.Visible = 'off';  
            app.otpr7.Visible = 'off'; 
            app.otpr6.Visible = 'off'; pause(1);
            app.otpr5.Visible = 'off'; 
            app.otpr4.Visible = 'off';
            app.error1.Visible = 'off'; 
            app.error2.Visible = 'off'; 
            app.otpr3.Visible = 'off'; pause(1);
            app.otpr2.Visible = 'off'; 
            app.otpr1.Visible = 'off';
        
            % --- CASE 1: If all 5 done (recheck mode) ---
            if app.allDone
                % Go to next situation automatically (no orange)
                app.situationIndex = app.situationIndex + 1;
        
                % If reach beyond 5, restart recheck loop
                if app.situationIndex > 5
                    app.situationIndex = 1;
                end
        
                % Show same results directly
                if app.situationResults(app.situationIndex) == 1
                    app.Lamp.Color = [0 1 0]; % Green
                else
                    app.Lamp.Color = [1 0 0]; % Red
                end
        
                % Keep ready for next recheck
                app.readyForNext = true;
                return;
            end
        
            % --- CASE 2: Normal simulation (first loop) ---
            app.readyForNext = true;
            app.Lamp.Color = [1 0.65 0]; % Orange
        end

        % Button pushed function: ResetSimulationButton
        function ResetSimulationButtonPushed(app, event)


    app.simulationActive = false;   

    % Reset simulation state
    app.situationIndex = 0;
    app.randomOrder = [];
    app.UITable.Data = {};
    app.RunTimeLabel.Text = 'Run Time: 0';
    app.Lamp.Color = [0.5 0.5 0.5]; % gray
    app.success.Visible = 'off';
    app.error1.Visible = 'off';
    app.error2.Visible = 'off';

    app.success.Visible = 'off';  
    app.otpr7.Visible = 'off'; 
    app.otpr6.Visible = 'off'; 
    app.otpr5.Visible = 'off'; 
    app.otpr4.Visible = 'off';
    app.error1.Visible = 'off'; 
    app.error2.Visible = 'off'; 
    app.otpr3.Visible = 'off'; 
    app.otpr2.Visible = 'off'; 
    app.otpr1.Visible = 'off';

    % Clear all edit fields
    for i = 1:5
        fieldName = sprintf('EditField_%d', i);
        if isprop(app, fieldName)
            app.(fieldName).Value = '';
        end
    end

    cla(app.UIAxes)
    cla(app.UIAxes1_2)
    cla(app.UIAxes3)
    cla(app.UIAxes4)
    cla(app.UIAxes4_2)
    cla(app.UIAxes5)
    cla(app.UIAxes5_2)
    cla(app.UIAxes6)
    cla(app.UIAxes6_2)
    cla(app.UIAxes7)
    cla(app.UIAxes8)
    cla(app.UIAxes9)
    cla(app.UIAxes9_2)
    cla(app.UIAxes10)
    cla(app.UIAxes10_2)
    cla(app.UIAxes11)
    cla(app.UIAxes11_2)

    app.trialLabel.Text = sprintf('Received Signals at Used Subcarriers (OFDM Symbol 0) at Trial 0');


    msgbox('Simulation has been reset. You can now start again.', 'Reset Complete');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel_4 = uipanel(app.SimulationTab);
            app.GridLayout = uigridlayout(app.Panel_4);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartSimulationButton = uibutton(app.GridLayout2, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetSimulationButton = uibutton(app.GridLayout2, 'push');
            app.ResetSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulationButtonPushed, true);
            app.ResetSimulationButton.Text = 'Reset Simulation';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.EstimatingLabel = uilabel(app.GridLayout4);
            app.EstimatingLabel.Text = 'Estimating';
            app.IllegalAccessLabel = uilabel(app.GridLayout4);
            app.IllegalAccessLabel.Text = 'Illegal Access';
            app.LegitimateAccessLabel = uilabel(app.GridLayout4);
            app.LegitimateAccessLabel.Text = 'Legitimate Access';
            app.Lamp_2 = uilamp(app.GridLayout4);
            app.Lamp_3 = uilamp(app.GridLayout4);
            app.Lamp_4 = uilamp(app.GridLayout4);
            app.Panel_6 = uipanel(app.SimulationTab);
            app.ThreatDetectionPanelLabel = uilabel(app.Panel_6);
            app.ThreatDetectionPanelLabel.Text = 'Threat Detection Panel';
            app.Panel_7 = uipanel(app.SimulationTab);
            app.GridLayout5 = uigridlayout(app.Panel_7);
            app.EditField_1 = uieditfield(app.GridLayout5, 'text');
            app.EditField_2 = uieditfield(app.GridLayout5, 'text');
            app.EditField_3 = uieditfield(app.GridLayout5, 'text');
            app.EditField_4 = uieditfield(app.GridLayout5, 'text');
            app.EditField_5 = uieditfield(app.GridLayout5, 'text');
            app.UITable = uitable(app.SimulationTab);
            app.UITable.ColumnName = {'Simulation'; 'Date'; 'Time'; 'Status'};
            app.UITable.RowName = {};
            app.Panel_12 = uipanel(app.SimulationTab);
            app.ThreatMitigationPanelLabel = uilabel(app.Panel_12);
            app.ThreatMitigationPanelLabel.Text = 'Threat Mitigation Panel';
            app.Panel_9 = uipanel(app.SimulationTab);
            app.Image = uiimage(app.Panel_9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'figures', 'bts-icon.png');
            app.otpr5 = uibutton(app.Panel_9, 'push');
            app.otpr5.Visible = 'off';
            app.otpr7 = uibutton(app.Panel_9, 'push');
            app.otpr7.Visible = 'off';
            app.otpr8 = uibutton(app.Panel_9, 'push');
            app.otpr8.Visible = 'off';
            app.otpr4 = uibutton(app.Panel_9, 'push');
            app.otpr4.Visible = 'off';
            app.otpr1 = uibutton(app.Panel_9, 'push');
            app.otpr1.Visible = 'off';
            app.error2 = uiimage(app.Panel_9);
            app.error2.Visible = 'off';
            app.error2.ImageSource = fullfile(pathToMLAPP, 'figures', 'cross-icon.png');
            app.error1 = uiimage(app.Panel_9);
            app.error1.Visible = 'off';
            app.error1.ImageSource = fullfile(pathToMLAPP, 'figures', 'cross-icon.png');
            app.otpr6 = uibutton(app.Panel_9, 'push');
            app.otpr6.Visible = 'off';
            app.success = uiimage(app.Panel_9);
            app.success.Visible = 'off';
            app.success.ImageSource = fullfile(pathToMLAPP, 'figures', 'check-icon.png');
            app.BaseTranscieverStationLabel = uilabel(app.Panel_9);
            app.BaseTranscieverStationLabel.Text = 'Base Transciever Station';
            app.TVWSOperatorLabel = uilabel(app.Panel_9);
            app.TVWSOperatorLabel.Text = 'TVWS Operator';
            app.Image_2 = uiimage(app.Panel_9);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'figures', 'tower-icon.png');
            app.Panel_3 = uipanel(app.SimulationTab);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.TVWSOperatorButton = uibutton(app.GridLayout3, 'push');
            app.TVWSOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @TVWSOperatorButtonPushed, true);
            app.TVWSOperatorButton.Text = 'TVWS Operator';
            app.Lamp = uilamp(app.GridLayout3);
            app.RunTimeLabel = uilabel(app.GridLayout3);
            app.RunTimeLabel.Text = 'Run Time: 0';
            app.Panel_5 = uipanel(app.SimulationTab);
            app.SimulationControlsLabel = uilabel(app.Panel_5);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.Panel_15 = uipanel(app.SimulationTab);
            app.AccessBTSLabel_2 = uilabel(app.Panel_15);
            app.AccessBTSLabel_2.Text = 'Access BTS';
            app.Panel_17 = uipanel(app.SimulationTab);
            app.UIAxes9_2 = uiaxes(app.Panel_17);
            app.UIAxes11_2 = uiaxes(app.Panel_17);
            app.UIAxes10_2 = uiaxes(app.Panel_17);
            app.UIAxes4_2 = uiaxes(app.Panel_17);
            app.otpr2 = uibutton(app.SimulationTab, 'push');
            app.otpr2.Visible = 'off';
            app.otpr3 = uibutton(app.SimulationTab, 'push');
            app.otpr3.Visible = 'off';
            app.Panel_18 = uipanel(app.SimulationTab);
            app.UIAxes1_2 = uiaxes(app.Panel_18);
            title(app.UIAxes1_2, 'Legitimate TVWSO Frequency Tones')
            zlabel(app.UIAxes1_2, 'Z')
            app.UIAxes5_2 = uiaxes(app.Panel_18);
            title(app.UIAxes5_2, 'OFDM-compliant TVWSO Frequency Tones')
            app.UIAxes6_2 = uiaxes(app.Panel_18);
            title(app.UIAxes6_2, 'Cyclic prefix-violating Illegal TVWSO Frequency Tones')
            app.trialLabel_2 = uilabel(app.Panel_18);
            app.trialLabel_2.Text = 'Received Signals Characterization (OFDM Symbol 50) at Trial 0';
            app.CharacterizationTab = uitab(app.TabGroup);
            app.CharacterizationTab.Title = 'Characterization';
            app.UIAxes = uiaxes(app.CharacterizationTab);
            title(app.UIAxes, 'Legitimate TVWSO Frequency Tones')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes5 = uiaxes(app.CharacterizationTab);
            title(app.UIAxes5, 'OFDM-compliant TVWSO Frequency Tones')
            app.UIAxes6 = uiaxes(app.CharacterizationTab);
            title(app.UIAxes6, 'Cyclic prefix-violating Illegal TVWSO Frequency Tones')
            app.trialLabel = uilabel(app.CharacterizationTab);
            app.trialLabel.Text = 'Received Signals at Used Subcarriers (OFDM Symbol 50) at Trial 0';
            app.CategorizationTab = uitab(app.TabGroup);
            app.CategorizationTab.Title = 'Categorization';
            app.UIAxes7 = uiaxes(app.CategorizationTab);
            app.UIAxes10 = uiaxes(app.CategorizationTab);
            app.UIAxes8 = uiaxes(app.CategorizationTab);
            app.UIAxes9 = uiaxes(app.CategorizationTab);
            app.AvgoverTrialTab = uitab(app.TabGroup);
            app.AvgoverTrialTab.Title = 'Avg over Trial';
            app.UIAxes3 = uiaxes(app.AvgoverTrialTab);
            app.UIAxes4 = uiaxes(app.AvgoverTrialTab);
            app.UIAxes11 = uiaxes(app.AvgoverTrialTab);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '.';
            app.UIAxes12 = uiaxes(app.Tab_3);
            app.UIAxes13 = uiaxes(app.Tab_3);
            app.UIAxes14 = uiaxes(app.Tab_3);
            app.ResetTVWSOpeatorButton = uibutton(app.UIFigure, 'push');
            app.ResetTVWSOpeatorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTVWSOpeatorButtonPushed, true);
            app.ResetTVWSOpeatorButton.Text = 'Reset TVWS Opeator';
            app.Button2_2 = uibutton(app.UIFigure, 'push');
            app.Button2_2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Latest_SISO_APP_06152026

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end